classdef loaded < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Lamp                            matlab.ui.control.Lamp
        FinalResultEditField            matlab.ui.control.EditField
        FinalResultEditFieldLabel       matlab.ui.control.Label
        ActualLabelEditField            matlab.ui.control.EditField
        ActualLabelEditFieldLabel       matlab.ui.control.Label
        ResultStatisticalEditField      matlab.ui.control.EditField
        ResultStatisticalEditFieldLabel  matlab.ui.control.Label
        ResultMachineLearningEditField  matlab.ui.control.EditField
        ResultMachineLearningEditFieldLabel  matlab.ui.control.Label
        UploadPatientDataButton         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        UserData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function LoadModelButtonPushed(app, event)

        end

        % Callback function
        function ClassifyButtonPushed(app, event)
            
        end

        % Button pushed function: UploadPatientDataButton
        function UploadPatientDataButtonPushed(app, event)
          % Open file selection dialog box
            [file, path] = uigetfile('*.csv', 'Select CSV file');
            optsFull = detectImportOptions('MIT_BIH_Arrhythmia_Database.csv');
            optsFull.VariableNamingRule = 'preserve';  % Preserve original column headers
            ecgData = readtable('MIT_BIH_Arrhythmia_Database.csv', optsFull);

            if isequal(file, 0)
                disp('User selected Cancel');
            else
                % Full path of the file
                fullPath = fullfile(path, file);
            
                % Load data, preserving original column names
                opts = detectImportOptions(fullPath, 'VariableNamingRule', 'preserve');
                data = readtable(fullPath, opts);
            
                % Assuming rowNumber is still 421 for demonstration; adjust as needed
                rowNumber = 1;
                if height(data) < rowNumber
                    disp('Selected file has fewer rows than expected. Please select another file.');
                    return;
                end
            
                try
                    % Load the trained SVM model and preprocessing parameters
                    % Ensure these files are in the MATLAB path or current folder
                    load('SVMModel.mat');
                    load('preprocessingParams.mat'); % Load mu, sigma, and coeff
            
                    % Load threshold values
                    load('thresholds.mat'); % This should contain RR_threshold, QRS_duration_threshold, and QRS_amplitude_threshold
            
                    % Extract relevant features from the loaded data for a specific row
                    RR_input = data.('0_pre-RR')(rowNumber);
                    QRS_duration_input = data.('0_qrs_interval')(rowNumber);
                    QRS_amplitude_input = data.('0_rPeak')(rowNumber) - data.('0_qPeak')(rowNumber);
            
                    % Check user-input data against loaded thresholds
                    is_arrhythmia_threshold = (RR_input > RR_threshold) | (QRS_duration_input > QRS_duration_threshold) | (QRS_amplitude_input < QRS_amplitude_threshold);
            
                    % Select a single data point
                    newData = data(rowNumber, 3:end);
            
                    % Normalize the input data using the same mean and standard deviation
                    newDataArray = table2array(newData);
                    newDataNormalized = (newDataArray - mu) ./ sigma;
            
                    % Reduce the dimensionality using PCA
                    numComponents = 4; % Adjust as necessary
                    newDataReduced = newDataNormalized * coeff(:, 1:min(size(coeff, 2), numComponents));
            
                    % Predict the label for the new data point using SVM
                    predictedLabelSVM = predict(SVMModel, newDataReduced);
            
                    % Define the classes to consider as Arrhythmia
                    arrhythmiaClasses = {'VEB', 'SVEB', 'F', 'Q'};
            
                    % Display the results
                    resultTextThreshold = '';
                    threshold = 0
                    if is_arrhythmia_threshold
                        threshold = 1;
                        resultTextThreshold = 'Arrhythmia';
                    else
                        resultTextThreshold = 'Normal';
                    end
            
                    % Check if the SVM model predicts Arrhythmia
                    resultTextSVM = '';
                    svm = 0;
                    if ismember(char(data.type(rowNumber)), arrhythmiaClasses) && strcmp(char(predictedLabelSVM), 'Arrhythmia')
                        svm = 1;
                        resultTextSVM = 'Arrhythmia';

                    else
                        resultTextSVM = 'Normal';
                    end
                    
                    if(threshold == svm)
                        resultFinalText = resultTextSVM;
                    else
                        resultFinalText = resultTextThreshold;
                    end

                   if strcmp(resultFinalText, 'Arrhythmia')
                        app.Lamp.Color = [1,0,0];
                    else
                        app.Lamp.Color = [0,1,0];
                    end
                    actualLabel = char(data.type(rowNumber));
                    
                    % Check if there's a mismatch and plot QRS peaks
                    if (threshold ~= svm) || (threshold==svm)
                        disp("Hello");
                        disp(data.record);
                        % Extract all data for the record from the main dataset
                        recordData = ecgData(ecgData.record == data.record(rowNumber), :);
                        disp(recordData);

                        % Sample rate (assuming 360 samples per second)
                        sample_rate = 360;
                    
                        % Total number of samples
                        total_samples = height(recordData);
                    
                        % Duration of the signal in seconds
                        duration_seconds = total_samples / sample_rate;
                    
                        % Create time array based on the sample rate
                        time = linspace(0, duration_seconds, total_samples);
                    
                        % Clear previous plots
                        cla(app.UIAxes);
                        
                        % Plot ECG signal (0_rPeak)
                        plot(app.UIAxes, time, recordData.('0_rPeak'), 'b-', 'DisplayName', '0_rPeak');
                        hold(app.UIAxes, 'on');
                    
                        % Plot ECG signal (0_sPeak)
                        plot(app.UIAxes, time, recordData.('0_sPeak'), 'g--', 'DisplayName', '0_sPeak');
                    
                        % Plot ECG signal (0_qPeak)
                        plot(app.UIAxes, time, recordData.('0_qPeak'), 'r:', 'DisplayName', '0_qPeak');
                        
                        % QRS peak detection
                        [~, qrs_locs] = findpeaks(recordData.('0_sPeak'), 'MinPeakDistance', round(0.2 * sample_rate), 'MinPeakHeight', 0.5);
                        
                        % Plot QRS peaks
                        plot(app.UIAxes, time(qrs_locs), recordData.('0_sPeak')(qrs_locs), 'yo', 'DisplayName', 'QRS Peaks');
                        
                        % Enhance the plot
                        grid(app.UIAxes, 'on');
                        title(app.UIAxes, ['Rhythm Plot for Record ', num2str(data.record(rowNumber))]);
                        xlabel(app.UIAxes, 'Time (s)'); % Update x-axis label
                        ylabel(app.UIAxes, 'Amplitude');
                    
                        % Update legend
                        legend(app.UIAxes, 'show');
                    
                        hold(app.UIAxes, 'off');
                    end


                    % Update app labels with the results
                    app.ResultStatisticalEditField.Value = resultTextThreshold;
                    app.ResultMachineLearningEditField.Value = resultTextSVM;
                    app.ActualLabelEditField.Value = actualLabel;
                    app.FinalResultEditField.Value = resultFinalText;
                catch ME
                    % Display detailed error message
                    disp(getReport(ME));
                end
            end


        end

        % Value changed function: ResultMachineLearningEditField
        function ResultMachineLearningEditFieldValueChanged(app, event)
            value = app.ResultMachineLearningEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UploadPatientDataButton = uibutton(app.UIFigure, 'push');
            app.UploadPatientDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadPatientDataButtonPushed, true);
            app.UploadPatientDataButton.Text = 'Upload Patient Data';
            app.ResultMachineLearningEditFieldLabel = uilabel(app.UIFigure);
            app.ResultMachineLearningEditFieldLabel.Text = 'Result(Machine Learning)';
            app.ResultMachineLearningEditField = uieditfield(app.UIFigure, 'text');
            app.ResultMachineLearningEditField.ValueChangedFcn = createCallbackFcn(app, @ResultMachineLearningEditFieldValueChanged, true);
            app.ResultStatisticalEditFieldLabel = uilabel(app.UIFigure);
            app.ResultStatisticalEditFieldLabel.Text = 'Result (Statistical)';
            app.ResultStatisticalEditField = uieditfield(app.UIFigure, 'text');
            app.ActualLabelEditFieldLabel = uilabel(app.UIFigure);
            app.ActualLabelEditFieldLabel.Text = 'Actual Label';
            app.ActualLabelEditField = uieditfield(app.UIFigure, 'text');
            app.FinalResultEditFieldLabel = uilabel(app.UIFigure);
            app.FinalResultEditFieldLabel.Text = 'Final Result';
            app.FinalResultEditField = uieditfield(app.UIFigure, 'text');
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loaded

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end